classdef fcsSOFI_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FilenameextEditFieldLabel      matlab.ui.control.Label
        TrialnumberEditFieldLabel      matlab.ui.control.Label
        FilenameextEditField           matlab.ui.control.EditField
        TrialnumberEditField           matlab.ui.control.NumericEditField
        PixelsizenmEditFieldLabel      matlab.ui.control.Label
        PixelsizenmEditField           matlab.ui.control.NumericEditField
        dTsEditFieldLabel              matlab.ui.control.Label
        dTsEditField                   matlab.ui.control.NumericEditField
        xminEditFieldLabel             matlab.ui.control.Label
        xminEditField                  matlab.ui.control.NumericEditField
        xmaxEditFieldLabel             matlab.ui.control.Label
        xmaxEditField                  matlab.ui.control.NumericEditField
        yminEditField_3Label           matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        ymaxEditField                  matlab.ui.control.NumericEditField
        tminEditFieldLabel             matlab.ui.control.Label
        tminEditField                  matlab.ui.control.NumericEditField
        tmaxEditFieldLabel             matlab.ui.control.Label
        tmaxEditField                  matlab.ui.control.NumericEditField
        DiffusionModelButtonGroup      matlab.ui.container.ButtonGroup
        BrownianButton                 matlab.ui.control.ToggleButton
        CompBrownianButton             matlab.ui.control.ToggleButton
        AnomalousButton                matlab.ui.control.ToggleButton
        SavefiguresButtonGroup         matlab.ui.container.ButtonGroup
        YesButton                      matlab.ui.control.ToggleButton
        NoButton                       matlab.ui.control.ToggleButton
        SavedataButtonGroup            matlab.ui.container.ButtonGroup
        YesButton_2                    matlab.ui.control.ToggleButton
        NoButton_2                     matlab.ui.control.ToggleButton
        MicroscopyParametersLabel      matlab.ui.control.Label
        RegionofInterestinPixelsLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        fcsSOFILabel                   matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        LoadDataLabel                  matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        DisplayROIButton               matlab.ui.control.Button
        DisplayFigureDropDownLabel     matlab.ui.control.Label
        DisplayFigureDropDown          matlab.ui.control.DropDown
        ProgressTextArea               matlab.ui.control.TextArea
        BrowseButton                   matlab.ui.control.Button
        ManualLoadLabel                matlab.ui.control.Label
        RowIndexEditFieldLabel         matlab.ui.control.Label
        RowIndexEditField              matlab.ui.control.NumericEditField
        ColumnIndexEditFieldLabel      matlab.ui.control.Label
        ColumnIndexEditField           matlab.ui.control.NumericEditField
        DisplaySinglePixelCurveFitButton  matlab.ui.control.Button
        SinglePixelFitResultsLabel     matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SinglePixelTextArea            matlab.ui.control.TextArea
        August52020Label               matlab.ui.control.Label
        DiffusionConstantRangeCaxisscaleLabel  matlab.ui.control.Label
        ModelParameterSettingsLabel    matlab.ui.control.Label
        MapSettingsLabel               matlab.ui.control.Label
        AnomalousmodelonlyLabel        matlab.ui.control.Label
        CompmodelonlyLabel             matlab.ui.control.Label
        RedrawfcsSOFIButton            matlab.ui.control.Button
        ofPSFpixelsLabel               matlab.ui.control.Label
        FWHMEditField                  matlab.ui.control.NumericEditField
        StoreExecTimesButtonGroup      matlab.ui.container.ButtonGroup
        YesButton_3                    matlab.ui.control.ToggleButton
        NoButton_3                     matlab.ui.control.ToggleButton
        DstartpointnmsLabel            matlab.ui.control.Label
        DstpEditField                  matlab.ui.control.NumericEditField
        DstartpointnmsLabel_2          matlab.ui.control.Label
        D2stpEditField                 matlab.ui.control.NumericEditField
        alphastartpointEditFieldLabel  matlab.ui.control.Label
        alpha_stpEditField             matlab.ui.control.NumericEditField
        maximumalphaallowedEditFieldLabel  matlab.ui.control.Label
        alpha_threshEditField          matlab.ui.control.NumericEditField
        cminlogDnmsLabel               matlab.ui.control.Label
        cminEditField                  matlab.ui.control.NumericEditField
        cmaxlogDnm2s1EditFieldLabel    matlab.ui.control.Label
        cmaxEditField                  matlab.ui.control.NumericEditField
        WeightedfittingButtonGroup     matlab.ui.container.ButtonGroup
        YesButton_4                    matlab.ui.control.ToggleButton
        NoButton_4                     matlab.ui.control.ToggleButton
        SOFIsaturationcutoffLabel      matlab.ui.control.Label
        SOFIsaturationcutoffEditField  matlab.ui.control.NumericEditField
        R2cutoffEditFieldLabel         matlab.ui.control.Label
        R2cutoffEditField              matlab.ui.control.NumericEditField
        shiftEditFieldLabel            matlab.ui.control.Label
        shiftEditField                 matlab.ui.control.NumericEditField
        scaleEditFieldLabel            matlab.ui.control.Label
        scaleEditField                 matlab.ui.control.NumericEditField
        NormcoefLabel                  matlab.ui.control.Label
        normcoefEditField              matlab.ui.control.NumericEditField
        stepsEditFieldLabel            matlab.ui.control.Label
        stepsEditField                 matlab.ui.control.NumericEditField
        satmaxEditField_2Label         matlab.ui.control.Label
        satmaxEditField                matlab.ui.control.NumericEditField
        satminEditFieldLabel           matlab.ui.control.Label
        satminEditField                matlab.ui.control.NumericEditField
        HueSettingsLabel               matlab.ui.control.Label
        SaturationSettingsLabel        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        MinimumthresholdLabel          matlab.ui.control.Label
        alpha_minEditField             matlab.ui.control.NumericEditField
        CustomROICheckBox              matlab.ui.control.CheckBox
    end

%% properties
properties (Access = private)
   
    
    parameter = 'dataset'; %generic data set file name extension
    trialnos=77; %data set number (can analyze multiple data sets by entering multiple numbers)

    %diffusion coefficient parameters
    pixelsize=50; %in nm; needed to accurately calculate D
    dT=0.04; %in s; needed to accurately calculate D
    FWHM = 2.7;

    %caxis scale  %for simulated data
    minScale = 0;
    maxScale = 15000;

    % custom ROI
    custom_ROI = 0;
    
    %region of interest in pixels for custom ROI
    xmin=1; xmax=30;
    ymin=1; ymax=30;
    tmin = 1; tmax= 5000;
    
    %choose type of diffusion (1 = Brownian, 2 = 2-Comp Brownian, 3 = Anomalous)
    type = 1;
    
    % tau_D start point (2-comp diffusion model only)
    D_stp = 1.7e3;
    td_stp;
    D2_stp = 0.85e3;
    td2_stp;
   
    %choose alpha start point (Anomalous diffusion model only)
    alpha_stp = 1;
    
    % R2 cutoff
    R2_cutoff = 0;
    
    %alpha threshold
    alpha_thresh = 2; % maximum value of alpha allowed to appear on alpha map
    alpha_min = 0;
    
    % SOFI saturation cutoff
    SOFI_sat_cutoff = 0.02;
    
    %set SOFI saturation limits
    cminAC=0; % line 853 in GUI code
    cmaxAC=1e9;

    %INPUTS FOR COLOR RESCALING
    %set caxis limits for fcsSOFI diffusion map (color scaling)
    cmin=2;
    cmax=6;
    %control hue
    shift = 0.05;
    scale = 0.7;
    normcoef = 10;
    steps = 25;
    %control saturation
    satmax = 0.5;
    satmin = 0;

    %plot figures? (1 = yes)
    plotfigures=1;
    
    % store execution times in external text file? (1 = yes);
    store_execution_times = 0;

    %save figures? 
    savefigures = 0;

    %save data files? (1 = yes)
    savethedata = 0; 

    %number of fits per pixel
    number_fits = 1000;

    %single pixel curve fit plot
    row_index = 15; %pixel row index
    column_index = 15; %pixel column index
    
    % fit weights
    toggle_weights = 0; % 1 = yes, use weights
    
    % Other properties
    browse;
    browsed_file;
    date;
    im;
    converged_parameters;
    avgimage;
    DataCombined;
    hsv2rgbmap;
    hsv2rgbmap_deconv
    hsv2rgbmap2;
    rgb_cmap_rs;
    R2map;
    srmap_hsv;
    srmap_hsv_deconv;
    Dmap;
    D2map;
    Dmap_corrected;
    D2map_corrected
    Dmap2log;
    D2map2log;
    Dmap_hsv;
    D2map_hsv;
    normDmap2log;
    normD2map2log;
    alphamap;
    fitresult2;
    totaltime;
    szmap1;
    sz2map1;
    szmap2;
    sz2map2;
    filtim;
    norm_ca_AC_deconv;
    norm_ca_AC;
    fname;
    avgim;
    figfolder;

    
end

%% methods
methods (Access = private)
    
    %% CalcCorr function    
    function [ACXC_all] = CalcCorr(app,innerpts,DataVector,c,L)
        %i range accomodates time offset
        i = 1;
        for i = 1:length(innerpts)-2        
            %taking only indices we care about
            index = innerpts(i);
            %Fluorescence intensity calculations        
            %fluorescence fluctuation
            F = DataVector(:,index);
            dF = F - mean(F);

            %dF of all surrounding points
            dFD = DataVector(:, index+1)- mean(DataVector(:, index+1));
            dFR = DataVector(:, index+L+1)- mean(DataVector(:, index+L+1));
            dFDR = DataVector(:, index+L+2)- mean(DataVector(:, index+L+2));

            %% Perform AC, XC calculations
            %autocorrelating and cross-correlating with xcorr
            [AC, lags] = xcorr(dF, dF); 

            %min_lags staring at zero
            min_lags = (length(lags)-1)/2+1;
            max_lags=length(lags);

            %fluctuations about time offset
            F2 = DataVector(:, index+1);
            dF2 = F2-mean(F2);
            F3 = DataVector(:, index+2);
            dF3 = F3- mean(F3);      

            % 2nd order correlation
            [corrCombined2]=rcSOFI_2ndOrder(app,AC,  min_lags,max_lags);        

            %LK-add storing the AC and xcorr curves for each pixel, i
            ACXC_all(1,i)=struct('Order2',corrCombined2);
            i = i+1;
        end   
    end

   %% rotgauss function
   function val = rotgauss(app,x, y, theta, FWHMx, FWHMy, offset, factor, center)
        xc      = center(1);
        yc      = center(2);
        theta   = (theta/180)*pi;
        xm      = (x-xc)*cos(theta) - (y-yc)*sin(theta);
        ym      = (x-xc)*sin(theta) + (y-yc)*cos(theta);
        u       = (xm/FWHMx)^2 + (ym/FWHMy)^2;
        val     = offset + factor*exp(-u/2);
   end


    %% customgauss function        
    function ret = customgauss(app,gsize, FWHMx, FWHMy, theta, offset, factor, center)
        ret     = zeros(gsize);
        rbegin  = -round(gsize(1) / 2);
        cbegin  = -round(gsize(2) / 2);
        for r=1:gsize(1)
        for c=1:gsize(2)
            ret(r,c) = rotgauss(app,rbegin+r,cbegin+c, theta, FWHMx, FWHMy, offset, factor, center);
        end
        end
    end
        
    %% getUpperLeft function        
    function [selectedpts] = getUpperLeft(app,length, width, startingpt)
        selectedpts = [];
        for n = 1:width-1
            newpts = startingpt+(n-1)*length:startingpt+n*length-2;
            selectedpts = [selectedpts newpts];
            n = n+1;
        end
    end

    %% logbindata function        
    function [new_lags, new_AC] = logbindata(app,lags,AC_aver,ddwell,max_lag)
        % determine the lowest and higher order
        low_flag = 0;
        hi_flag = 0;
        
        for i=1:10 
            if (ddwell/10^(-i) >= 0.999) && low_flag == 0
                low_order = -i; % find the lowest order of magnitude in the time scale
                low_flag = 1;
            end
            
            if max_lag > 1
                if (max_lag/10^(i) <= 1) && hi_flag ==0
                    hi_order = i; % find the highest order of magnitude in the time scale
                    hi_flag = 1;
                end
            
            elseif max_lag < 1 % in case the max time is less than 1 s
                if (max_lag/10^(-i) >= 0.9999) && hi_flag ==0
                    hi_order = -i+1; % find the highest order of magnitude in the time scale
                    hi_flag = 1;
                end
            
            else
            fprintf('\noops, something is wrong with max_lag');
            fprintf('\nexiting');
            end
        end

        % ['low_order', 'hi_order']
        [low_order, hi_order];

        % compute the number of bin before the next order
        n_first_bins = int32((10^(low_order+1))/(lags(1)*ddwell)) - 1;
    
        % compute the total number of bins
        nbins = n_first_bins + 9*(hi_order - (low_order + 1));
    
        % return
        new_lags = zeros(nbins,1);
        new_AC = zeros(nbins,1);
        npoints_per_bin = zeros(hi_order-(low_order+1),1);
    
        % fill the bins until the next order
        for ipoint=1:n_first_bins
            new_lags(ipoint) = lags(ipoint);
            new_AC(ipoint) = AC_aver(ipoint);
        end
    
        flag_stop = 0; % is used to stop filling the bins once the end of lags array is reached
    
    
        % start the loop to fill the rest of the bins
        for iorder = 1:(hi_order-(low_order+1))
            order = double(low_order + iorder); % else it can't do 10^(-int)
            npoints_per_bin(iorder) = int32(10.0^order/(lags(1)*ddwell));
        
            % loop over bins inside the order
            for ibin = 1:9
                bin_indx = n_first_bins + (iorder-1)*9 + ibin;
                
                for ipoint = 1:npoints_per_bin(iorder)
                        tmp_indx = n_first_bins;
                    for jorder = 1:(iorder-1)
                        tmp_indx = tmp_indx + 9*npoints_per_bin(jorder);
                    end
                        tmp_indx = tmp_indx + (ibin-1)*npoints_per_bin(iorder) + ipoint;
                        
                        
                    if (tmp_indx < length(lags))
                        point_indx = tmp_indx;
                    else
                        flag_stop  = 1;
                        break
                    end
                        
                    new_lags(bin_indx) = new_lags(bin_indx) + lags(point_indx)/npoints_per_bin(iorder);
                    new_AC(bin_indx) = new_AC(bin_indx) + AC_aver(point_indx)/npoints_per_bin(iorder);
                        
                end %loop over points
          
                if (flag_stop == 1)
                    break
                end
             end % loop over bins
                    
            if (flag_stop == 1)
                break
            end
            
         end % loop over orders
    
    
         % rid new_lags of zero values
         ind = find(new_lags);
         tmp = new_lags(ind);
         new_lags = tmp; 
         tmp = new_AC(ind);
         new_AC = tmp;
    end

    %% rcSOFI_2ndOrder function
    function [corrCombined] = rcSOFI_2ndOrder(app,AC,  min_lags,max_lags,lags)
         % 2nd order correlation
        AC2 = fft(AC(min_lags:max_lags)); %2nd order
        
        %out of Fourier space
        AC2 = ifft(AC2);       

        % 4X499 correlation data
        corrCombined = [AC2'];

    end
        

    %% gofStats function
        function [rsq,chisq,J,MSE,ci] = gofStats(app,type,parameters,fitresult,x,y)
        %% Residuals
        residuals = y - fitresult;
        a = (y - fitresult).^2./fitresult;
        a(isinf(a)) = 0;
        
        %% Chi-square
        chisq = sum(a);
        
        %% R-square
        rsq = 1-sum(residuals.^2)/sum(y.^2);
        
        %% Partial Derivatives and Jacobian Matrix
        % Brownian
        if type == 1
            % partial derivatives
            dGda = parameters(3)./(parameters(3)+x);
            dGdb = ones(size(x));
            dGdtau = (parameters(1).*x)./(parameters(3)+x).^2;
            
            % Jacobian
            J = [dGda dGdb dGdtau];J = reshape(J,[length(x),length(parameters)]);
            
        % 2-Componenet Brownian
        elseif type == 2
            % partial derivatives
            dGda1 = parameters(4)./(parameters(4)+x);
            dGda2 = parameters(5)./(parameters(5)+x);
            dGdb = ones(size(x));
            dGdtau1 = (parameters(1).*x)./(parameters(4)+x).^2;
            dGdtau2 = (parameters(2).*x)./(parameters(5)+x).^2;
            
            % Jacobian
            J = [dGda1 dGda2 dGdb dGdtau1 dGdtau2];J = reshape(J,[length(x),length(parameters)]);
            
        % Anomalous
        elseif type == 3
            %partial derivatives
            dGda = 1./((x./parameters(3)).^(parameters(4)+1));
            dGdb = ones(size(x));
            dGdtau = parameters(1)*parameters(4).*(x./parameters(3)).^(parameters(4))./(parameters(3).*((x/parameters(3)).^(parameters(4))).^2);
            dGdalpha = (parameters(1)*(x./parameters(3)).^(parameters(4)).*log(x./parameters(3)))./((x./parameters(3)).^(parameters(4)+1)).^2;
        
            % Jacobian
            J = [dGda dGdb dGdtau dGdalpha];J = reshape(J,[length(x),length(parameters)]);
            
        end
        
        %% Mean squared error
        MSE = sum((y - fitresult).^2)/length(y); 
        
        %% Confidence intervals
        
        ci = nlparci(parameters,residuals,'jacobian',J);

    end

end   

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ProgressTextArea, RunButton
        function RunButtonPushed(app, event)
            clc; close all; 

            %record time of trial
            app.date = datestr(now,'mm-dd-yyyy_HH-MM');
            
            % start timing fcsSOFI execution
            before = clock;
            
            % add Gpufit
            addpath(strcat(pwd,'\Gpufit_build-64_20190709\Debug\matlab'))
               
            %%% STEP 1: blink_AConly (SOFI)
            % start timing SOFI execution
            blink_before = clock;
            
            % update progress bar
            app.ProgressTextArea.Value = sprintf('Running...');
            drawnow
            
            %% start code/load and setup data
            
            if app.browse == 1
                app.fname = app.browsed_file;
            else
                app.fname = strcat(app.parameter,num2str(app.trialnos));
            end
            
            % Load data
            load(app.fname); fprintf(strcat('\n',app.fname,' loaded\n')); drawnow;
            app.DataCombined=Data;
            
             %region of interest in pixels (default to max size)
            if app.custom_ROI == 0    
                app.xmin=1; app.xmax=size(Data,1);
                app.ymin=1; app.ymax=size(Data,2);
                app.tmin = 1; app.tmax= size(Data,3);
                
            end
 
            % Set ROI
            app.DataCombined=app.DataCombined(app.ymin:app.ymax,app.xmin:app.xmax,app.tmin:app.tmax);
        
            % produce average image
            app.avgimage=sum(app.DataCombined(:,:,:),3)./size(app.DataCombined,3);
        
            % Reshape 2D image to 1D for analysis
            % shapes columnwise into a 1x(app.ymax-app.ymin+1)(app.xmax-app.xmin+1)
            DataVector = zeros(app.tmax,(app.xmax-app.xmin+1)*(app.ymax-app.ymin+1));
            for i=1:size(app.DataCombined,3)
                DataVector(i,:)=reshape(app.DataCombined(:,:,i),1,size(app.DataCombined(:,:,i),1)*...
                    size(app.DataCombined(:,:,i),2));
            end
        
            % part of distance factor-- c = FWHM    
            c = 3.5; %experimentally based
        
            % only upper left points are run through analysis to avoid repetition
            [innerpts] = getUpperLeft(app,app.ymax-app.ymin+1, app.xmax-app.xmin+1, 1);
            L = app.ymax-app.ymin;
            W = app.xmax-app.xmin;
        
            %% Calculate the correlation (2-4th orders, AC and XC)
            [ACXC_all]=CalcCorr(app,innerpts,DataVector,c,L); %calculate 
        
            %% Calculate intensity for images by different methods     
            AC_G2 = zeros(1,numel(ACXC_all));
            for i=1:numel(ACXC_all)
                % AC only - first point of different orders
                AC_G2(i) = ACXC_all(1,i).Order2(1,2);
            end
        
            %% Reshape matrices for images
            % reshape easy ones first
            AC_G2_im=[0,AC_G2,0];% pad matrices with first points, so back to original size
            AC_G2_im=reshape(AC_G2_im,L,W);% reshape
            
            M = zeros(size(AC_G2_im));
            M(1:end-1,:) = AC_G2_im(2:end,:);
            M(end,:) = circshift(AC_G2_im(1,:),numel(AC_G2_im(1,:))-1);
            AC_G2_im = M;
            
            size(M)
                   
%%%%%%%     %% Deconvolution
            app.avgim=app.avgimage;
            app.im=AC_G2_im;
            
            % define the PSF 
            intensity = 1;
            gauss1=customgauss(app,[100 100],app.FWHM,app.FWHM,0,0,intensity,[5 5]); %create a 2D PSF
            PSF=gauss1(45:65,45:65); %only use the center where the PSF is located at
            
            %%% TOGGLE DECONVOLUTION
            app.filtim=deconvlucy(app.im,PSF); % Based on Geissbuehler bSOFI paper
            %%%
            
            % display execution time of SOFI step
            blink_after = clock;
              app.ProgressTextArea.Value = sprintf('SOFI complete\nCurve fitting running, will update at 25%%');
              drawnow
              
            %%% STEP 2: BinFitData (fcs)
    
            Bin_before = clock;
            
            % ROI pixels of image to analyze
            xmn=1; xmx=app.xmax-app.xmin;
            ymn=1; ymx=app.ymax-app.ymin;
                
            %reshape ACXC_all to get ROI
            ACXC_all2=[ACXC_all,ACXC_all(1,1),ACXC_all(1,1)];
            ACXC_all_reshape=reshape(ACXC_all2,size(AC_G2_im));
            ACXC_all_ROI=ACXC_all_reshape(ymn:ymx,xmn:xmx);
            AC_XC_all_save=reshape(ACXC_all_ROI,1,size(ACXC_all_ROI,1)*size(ACXC_all_ROI,2));
            AC_all(1,1).curves=AC_XC_all_save;
            
            for i=1:numel(AC_all(1,1).curves)
                    % add raw AC curves
                ACadd(1,:)=AC_all(1,1).curves(1,i).Order2;
                AC_avg(1,i).curves=mean(ACadd,1);   
            end
            
            %% log bin the averaged data
            for i=1:numel(AC_all(1,1).curves)
                
                AC_aver=(AC_avg(1,i).curves);
                max_lag=numel(AC_avg(1,i).curves);
                lags=1:max_lag;
                ddwell=1;
            
                [new_lags, new_AC] = logbindata(app,lags,AC_aver,ddwell,max_lag);
                
                AC_logbin(i,:)=new_AC;
                AC_loglag(i,:)=new_lags;
                
            end
            
            % take first lag point to create super-resolution image
            AC_im = zeros(1,size(AC_logbin,1));
            for j=1:size(AC_logbin,1)
                AC_im(j)=AC_logbin(j,2);
            end
            
            rowdim=size(ACXC_all_ROI,1);
            coldim=size(ACXC_all_ROI,2);
            
            AC_im2=reshape([AC_im],rowdim,coldim);
            
            %% Set up curve fitting
            
            % initialize variable to keep track of GPU computation time
            fit_time = 0;
            
            % number of parameters
            number_parameters = [3; 5; 4]; number_parameters = number_parameters(app.type);
            
            % estimator id
            estimator_id = EstimatorID.LSE;
                
            % model ID
            model_id = [ModelID.BROWNIAN_1COMP; ModelID.BROWNIAN_2COMP; ModelID.ANOMALOUS]; model_id = model_id(app.type);
            
            % tolerance
            tolerance = 1e-3;
            
            % maximum number of iterations per pixel
            max_n_iterations = 1000;
            
            % preallocate variables 
            tauD = zeros(1,xmx*ymx); tauD2 = tauD; D = tauD; D2 = tauD; alpha = tauD;
            
            %% Perform curve fitting
            for i=1:size(AC_logbin,1)
                
                % display progress at 25%, 50%, and 75% complete
                if i == ceil(size(AC_logbin,1)/4)
                    app.ProgressTextArea.Value = sprintf('Curve-fitting 25%% complete');
                    drawnow
                end
                if i == ceil(size(AC_logbin,1)/2)
                    app.ProgressTextArea.Value = sprintf('Curve-fitting 50%% complete');
                    drawnow
                end
                if i == ceil(3/4*size(AC_logbin,1))
                    app.ProgressTextArea.Value = sprintf('Curve-fitting 75%% complete');
                    drawnow
                end
                
                % extract auto correlation curve 
                ACcurve=AC_logbin(i,:);
                timelag=AC_loglag(i,:);     
                
                x=timelag.*app.dT; %convert x values to seconds   
                y=ACcurve;
                
                %remove first timelag point tau=lag
                ind=numel(x);
                x=x(2:ind);
                y=y(2:ind);
                
                % start point for tauD in terms of D
                app.td_stp = (app.pixelsize.^2)/(4*app.D_stp);
       
                % declare start points
                switch app.type
                    case 1
                        start_points = [max(y)*2,mean(y(round((3*numel(y)/4)):numel(y))),app.td_stp];
                    case 2
                        % start point for tauD in terms of D
                        app.td2_stp = (app.pixelsize.^2)/(4*app.D2_stp);
                        start_points = [max(y),max(y),mean(y(round((3*numel(y)/4)):numel(y))),1/2*app.td_stp,app.td2_stp];
                    case 3
                        start_points = [max(y)*2, mean(y(round((3*numel(y)/4)):numel(y))), app.td_stp, app.alpha_stp];
                end
                
                
                % initial parameters
                initial_parameters = repmat(single(single(start_points)'), [1, app.number_fits]);
            
                % convert raw data to single precision and format for GPU fitting
                data = single(y); data = repmat(data(:), [1, app.number_fits]);
                
                % user info (independent variables)
                user_info = single(x);
                
                % weights
                switch app.toggle_weights
                    case 1
                        weights = data;
                        weights(data<=0) = 0;
                    case 0
                        weights = [];
                end

                % Run Gpufit
                [parameters, states, chi_squares, n_iterations, gputime] = gpufit(data, weights, ...
                 model_id, initial_parameters, tolerance, max_n_iterations, [], estimator_id, user_info);
            
                % converged parameters
                converged = states == 0; 
                app.converged_parameters = parameters(:, converged);
            
                % use raw parameters if poor convergence
                if isempty(app.converged_parameters) == 1
                    model_coefs = parameters(1:number_parameters);
                else
                    model_coefs = app.converged_parameters(1:number_parameters);
                end
                
                % construct fit result curve
                n_struct = struct('brownian',3,'brownian2comp',[4; 5],'anomalous',3);
                n_cell = struct2cell(n_struct);
                n = n_cell{app.type};
                len_x = numel(x);
                if app.type == 1
                    model_fit(1:len_x) = model_coefs(1).* (1./(1+(x(1:len_x)./model_coefs(3)))) + model_coefs(2);
                elseif app.type == 2
                    model_fit(1:len_x) = model_coefs(1).* (1./(1+(x(1:len_x)./model_coefs(4)))) + model_coefs(2).* (1./(1+(x(1:len_x)./model_coefs(5)))) + model_coefs(3);
                elseif app.type == 3
                    model_fit(1:len_x) = model_coefs(1).* (1./(1+(x(1:len_x)./model_coefs(3)).^model_coefs(4))) + model_coefs(2); 
                end
                
                % R-Square
                residuals = y - model_fit;
                a = (y - model_fit).^2./model_fit;
                a(isinf(a)) = 0;
                rsquare = 1-sum(residuals.^2)/sum(y.^2);
                
                % fit result structure
                fitresult(1,i)=struct('rawdata',[x',y'],'rsquare',rsquare,'model_fit',model_fit);
                
                % characteristic time
                tauD(i)=model_coefs(n(1)); % in in seconds
              
                % diffusion coefficient
                D(i)=(app.pixelsize.^2)/(4*tauD(i)); %in nm^2/s
                
                % second diffusion coefficient if using 2-component model
                if app.type == 2
                    tauD2(i)=model_coefs(n(2))*app.dT; % in in seconds
                    D2(i)=(app.pixelsize.^2)/(4*tauD(i));
                end
                
                % alpha map if using anomalous model
                if app.type == 3
                    alpha(i)= model_coefs(4);
                else
                    alpha(i)=0;
                end
                
                % compute total Gpufit time
                fit_time = fit_time + gputime;
            end
            
            %% Post fit data manipulation
            % reshape fit result 
            app.fitresult2=reshape(fitresult,rowdim,coldim);
            
            %Diffusion coefficient map
            app.Dmap=reshape(D,rowdim,coldim);
            app.Dmap = abs(app.Dmap);
            
            % create tauD map
            tauDmap=reshape([tauD],rowdim,coldim);
            
            % remove poor fits
            D_corrected = zeros(1,numel(D));
            for i=1:numel(D)
                if fitresult(1,i).rsquare<app.R2_cutoff
                    D_corrected(i)=0;
                else
                    D_corrected(i)=abs(D(i));
                end
            end
            app.Dmap_corrected=reshape([D_corrected],rowdim,coldim);
            
            % second diffusion coefficeint map if 2-component brownian model
            if app.type == 2
                app.D2map=reshape([D2],rowdim,coldim);
                tauD2map=reshape([tauD2],rowdim,coldim);
                D2_corrected = zeros(1,numel(D2));
            for i=1:numel(D2)
                if fitresult(1,i).rsquare<app.R2_cutoff
                    D2_corrected(i)=0;
                else
                    D2_corrected(i)=abs(D2(i));
                end
            end
            app.D2map_corrected=reshape([D2_corrected],rowdim,coldim);
            end
            
            % alpha map (anomalous diffusion)
            if app.type == 3    
                alpha_corrected = zeros(1,numel(alpha));
                % remove bad alphas
                for i=1:numel(alpha)
                    if fitresult(1,i).rsquare<app.R2_cutoff
                         alpha_corrected(i)=0;
                    elseif alpha(i)<= app.alpha_min
                        alpha_corrected(i)=0;
                    elseif alpha(i) > app.alpha_thresh
                        alpha_corrected(i)=0;
                    else
                        alpha_corrected(i)=abs(alpha(i));
                    end
                end
                app.alphamap=reshape([alpha_corrected],rowdim,coldim);
                       
%                 % graft alpha map onto super resolution map
%                 for i=1:size(app.alphamap,1)
%                     for j=1:size(app.alphamap,2)
%                         if app.srmap_hsv(i,j,1) <= app.SOFI_sat_cutoff
%                             app.alphamap(i,j,1) = 0;
%                         end
%                     end
%                 end
                
                app.alphamap(app.srmap_hsv(:,:,1)<=app.SOFI_sat_cutoff) = 0;
            else 
                app.alphamap = zeros(size(app.Dmap_corrected));
            
            end
           
            % make map of R^2 values
            R2 = zeros(1,numel(fitresult));
            for i=1:numel(fitresult)
                R2(i)=fitresult(1,i).rsquare;
            end
            app.R2map=reshape(R2,rowdim,coldim);
            
            name = ["Brownian","2-Component Brownian","Anomalous"];name = name(app.type);
            
            % display execution time of fcs step
            Bin_after = clock;
            app.ProgressTextArea.Value = sprintf('FCS complete'); drawnow
            
            %%% STEP 3: CombineTempSpat (fcs)
            
            % start timer for fcsSOFI combination
            Combine_before = clock;
           
            %% Diffusion map
            app.szmap1=size(app.Dmap,1);
            app.szmap2=size(app.Dmap,2);
            
            app.Dmap2log=log10(app.Dmap_corrected); 
            
            for i=1:size(app.Dmap2log,1)
                for j=1:size(app.Dmap2log,2)
                    if app.Dmap2log(i,j)==-Inf
                        app.Dmap2log(i,j)=0;
                    end
                end
            end
            
            %filter out poor fits if you want to here with an R2cutoff
            R2cutoff=0; %set R^2 cutoff (0-1); == 0, no filtering
            for i=1:size(app.Dmap2log,1)
                for j=1:size(app.Dmap2log,2)
                    if app.R2map(i,j)<R2cutoff
                        app.Dmap2log(i,j)=0;
                    end
                end
            end
            
%             app.normcoef=10; %instead set to standard here
            app.normDmap2log=app.Dmap2log./app.normcoef;

            for i=1:size(app.normDmap2log,1)
                for j=1:size(app.normDmap2log,2)
                    if app.normDmap2log(i,j)==0
                        app.normDmap2log(i,j)=0;
                    elseif app.normDmap2log(i,j)<app.cmin/app.normcoef
                        app.normDmap2log(i,j)=app.cmin/app.normcoef;
                    elseif app.normDmap2log(i,j)>app.cmax/app.normcoef
                        app.normDmap2log(i,j)=app.cmax/app.normcoef;
                    end
                end
            end
                 
            %% Create scaling/stretching/shifting factor to create colormap
            %changing shift, scale will change the colormap colors, range
            maxvalue=app.cmax/app.normcoef; %this is the max value on the colormap
%             app.shift=0.0;%shift left right
%             app.scale=0.7;%factor to multiply everything by
            
            for i=1:size(app.normDmap2log,1)
                for j=1:size(app.normDmap2log,2)
                    if app.normDmap2log(i,j)==0
                        app.normDmap2log(i,j)=0;
                    else
                        app.normDmap2log(i,j)=(app.normDmap2log(i,j)-app.shift).*app.scale;
                    end
                end
            end
            
            % %create colormap from the app.cmin/app.cmax
            ncmin=((app.cmin/app.normcoef)-app.shift).*app.scale;
            ncmax=((app.cmax/app.normcoef)-app.shift).*app.scale;
%             app.steps=25; %number of steps between min/max
            cgrad=[ncmin:((ncmax-ncmin)/app.steps):ncmax]';%create gradient
            
            %set cmap the same way as diffusion map 
            cmap(1:app.steps+1,1,1)=cgrad; %set hue = color
            cmap(1:app.steps+1,1,2)=ones(app.steps+1,1); %set saturation ("gray")
            cmap(1:app.steps+1,1,3)=ones(app.steps+1,1); %set brightness
            rgb_cmap=1-hsv2rgb(cmap); %convert to rgb
            app.rgb_cmap_rs = [rgb_cmap(:,:,1) rgb_cmap(:,:,2) rgb_cmap(:,:,3)];
            
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,1)=app.normDmap2log; %set hue = color
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,2)=ones(app.szmap1,app.szmap2); %set saturation ("gray")
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.Dmap_hsv=1-hsv2rgb(app.Dmap_hsv); %convert to rgb
            for i=1:size(app.Dmap_hsv,1) %filter out background rgb(D)=0, then make pixel black
                for j=1:size(app.Dmap_hsv,2)
                    if app.Dmap_hsv(i,j,1)==0
                        app.Dmap_hsv(i,j,:)=0;
                    end
                end
            end
                        
            %% make super resolution only map
            % set caxis limits for SR map
            cscaleACim=app.im;
            for i=1:size(cscaleACim,1) %filter out values above/below, change to limits
                for j=1:size(cscaleACim,2)
                    if cscaleACim(i,j)<app.cminAC
                        cscaleACim(i,j)=app.cminAC;
                    elseif cscaleACim(i,j)>app.cmaxAC
                        cscaleACim(i,j)=app.cmaxAC;
                    end
                end
            end
            app.norm_ca_AC =cscaleACim./(max(max(cscaleACim))); %normalize
            % deconvolved sr map
            cscaleACim=app.filtim;
            for i=1:size(cscaleACim,1) %filter out values above/below, change to limits
                for j=1:size(cscaleACim,2)
                    if cscaleACim(i,j)<app.cminAC
                        cscaleACim(i,j)=app.cminAC;
                    elseif cscaleACim(i,j)>app.cmaxAC
                        cscaleACim(i,j)=app.cmaxAC;
                    end
                end
            end
            app.norm_ca_AC_deconv=cscaleACim./(max(max(cscaleACim))); %normalize
            
            % HSV values
            app.srmap_hsv(1:app.szmap1,1:app.szmap2,1)=ones(app.szmap1,app.szmap2); %set hue = color
            % srmap_hsv(1:app.szmap1,1:app.szmap2,2)=AC_G2_im./(max(max(AC_G2_im))); %set saturation ("gray")
            % srmap_hsv(1:app.szmap1,1:app.szmap2,2)=AC_im2./(max(max(AC_im2))); %set saturation ("gray")
            app.srmap_hsv(1:app.szmap1,1:app.szmap2,2)=app.norm_ca_AC;
            app.srmap_hsv(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.srmap_hsv=1-hsv2rgb(app.srmap_hsv); %convert to rgb
            app.srmap_hsv=rgb2gray(app.srmap_hsv);% convert to gray scale            
            % deconvolved 
            app.srmap_hsv_deconv(1:app.szmap1,1:app.szmap2,1)=ones(app.szmap1,app.szmap2); %set hue = color
            % srmap_hsv(1:app.szmap1,1:app.szmap2,2)=AC_G2_im./(max(max(AC_G2_im))); %set saturation ("gray")
            % srmap_hsv(1:app.szmap1,1:app.szmap2,2)=AC_im2./(max(max(AC_im2))); %set saturation ("gray")
            app.srmap_hsv_deconv(1:app.szmap1,1:app.szmap2,2)=app.norm_ca_AC_deconv;
            app.srmap_hsv_deconv(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.srmap_hsv_deconv=1-hsv2rgb(app.srmap_hsv_deconv); %convert to rgb
            app.srmap_hsv_deconv=rgb2gray(app.srmap_hsv_deconv);% convert to gray scale
            
            %% combine D and Super res. for HSV
            hsvmap(1:app.szmap1,1:app.szmap2,1)=app.normDmap2log; %set hue = color
            hsvmap(1:app.szmap1,1:app.szmap2,2)=app.norm_ca_AC;
            hsvmap(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.hsv2rgbmap=1-hsv2rgb(hsvmap); %convert to rgb
            %deconvolved
            %% combine D and Super res. for HSV
            hsvmap_deconv(1:app.szmap1,1:app.szmap2,1)=app.normDmap2log; %set hue = color
            hsvmap_deconv(1:app.szmap1,1:app.szmap2,2)=app.norm_ca_AC_deconv;
            hsvmap_deconv(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.hsv2rgbmap_deconv=1-hsv2rgb(hsvmap_deconv); %convert to rgb
            
            
            if app.type == 2
                
                app.sz2map1=size(app.D2map,1);
                app.sz2map2=size(app.D2map,2);
                
                app.D2map2log=log10(app.D2map_corrected); 
                
                for i=1:size(app.D2map2log,1)
                    for j=1:size(app.D2map2log,2)
                        if app.D2map2log(i,j)==-Inf
                            app.D2map2log(i,j)=0;
                        end
                    end
                end
                
                %filter out poor fits if you want to here with an R2cutoff
                R2cutoff=0; %set R^2 cutoff (0-1); == 0, no filtering
                for i=1:size(app.D2map2log,1)
                    for j=1:size(app.D2map2log,2)
                        if app.R2map(i,j)<R2cutoff
                            app.D2map2log(i,j)=0;
                        end
                    end
                end
                
                % app.normcoef=10; %instead set to standard here
                app.normD2map2log=app.D2map2log./app.normcoef;
                
                for i=1:size(app.normD2map2log,1)
                    for j=1:size(app.normD2map2log,2)
                        if app.normD2map2log(i,j)==0
                            app.normD2map2log(i,j)=0;
                        elseif app.normD2map2log(i,j)<app.cmin/app.normcoef
                            app.normD2map2log(i,j)=app.cmin/app.normcoef;
                        elseif app.normD2map2log(i,j)>app.cmax/app.normcoef
                            app.normD2map2log(i,j)=app.cmax/app.normcoef;
                        end
                    end
                end
                
                %% Create scaling/stretching/shifting factor to create colormap
                %changing shift, scale will change the colormap colors, range
                maxvalue=app.cmax/app.normcoef; %this is the max value on the colormap
                % app.shift=0.0;%shift left right
                % app.scale=0.7;%factor to multiply everything by
                
                for i=1:size(app.normD2map2log,1)
                    for j=1:size(app.normD2map2log,2)
                        if app.normD2map2log(i,j)==0
                            app.normD2map2log(i,j)=0;
                        else
                            app.normD2map2log(i,j)=(app.normD2map2log(i,j)-app.shift).*app.scale;
                        end
                    end
                end
                
                % %create colormap from the app.cmin/app.cmax
                ncmin=((app.cmin/app.normcoef)-app.shift).*app.scale;
                ncmax=((app.cmax/app.normcoef)-app.shift).*app.scale;
                % app.steps=25; %number of steps between min/max
                cgrad=[ncmin:((ncmax-ncmin)/app.steps):ncmax]';%create gradient
                
                %set cmap the same way as diffusion map 
                cmap(1:app.steps+1,1,1)=cgrad; %set hue = color
                cmap(1:app.steps+1,1,2)=ones(app.steps+1,1); %set saturation ("gray")
                cmap(1:app.steps+1,1,3)=ones(app.steps+1,1); %set brightness
                rgb_cmap=1-hsv2rgb(cmap); %convert to rgb
                app.rgb_cmap_rs = [rgb_cmap(:,:,1) rgb_cmap(:,:,2) rgb_cmap(:,:,3)];
                
                app.D2map_hsv(1:app.szmap1,1:app.szmap2,1)=app.normD2map2log; %set hue = color
                app.D2map_hsv(1:app.szmap1,1:app.szmap2,2)=ones(app.sz2map1,app.sz2map2); %set saturation ("gray")
                app.D2map_hsv(1:app.szmap1,1:app.szmap2,3)=ones(app.sz2map1,app.sz2map2); %set brightness
                app.D2map_hsv=1-hsv2rgb(app.D2map_hsv); %convert to rgb
                for i=1:size(app.D2map_hsv,1) %filter out background rgb(D)=0, then make pixel black
                    for j=1:size(app.D2map_hsv,2)
                        if app.D2map_hsv(i,j,1)==0
                            app.D2map_hsv(i,j,:)=0;
                        end
                    end
                end
                
                %% make super resolution only map
                % set caxis limits for SR map
                cscaleACim=app.filtim;
                for i=1:size(cscaleACim,1) %filter out values above/below, change to limits
                    for j=1:size(cscaleACim,2)
                        if cscaleACim(i,j)<app.cminAC
                            cscaleACim(i,j)=app.cminAC;
                        elseif cscaleACim(i,j)>app.cmaxAC
                            cscaleACim(i,j)=app.cmaxAC;
                        end
                    end
                end
                app.norm_ca_AC_deconv=cscaleACim./(max(max(cscaleACim))); %normalize
                
                % HSV values
% %                 app.srmap_hsv(1:app.sz2map1,1:app.sz2map2,1)=ones(app.sz2map1,app.sz2map2); %set hue = color
%                 % srmap_hsv(1:app.szmap1,1:app.szmap2,2)=AC_G2_im./(max(max(AC_G2_im))); %set saturation ("gray")
%                 % srmap_hsv(1:app.szmap1,1:app.szmap2,2)=AC_im2./(max(max(AC_im2))); %set saturation ("gray")
%                 app.srmap2_hsv(1:app.sz2map1,1:app.sz2map2,2)=app.norm_ca_AC_deconv;
%                 app.srmap2_hsv(1:app.sz2map1,1:app.sz2map2,3)=ones(app.sz2map1,app.sz2map2); %set brightness
%                 app.srmap2_hsv=1-hsv2rgb(app.srmap2_hsv); %convert to rgb
%                 app.srmap2_hsv=rgb2gray(app.srmap2_hsv);% convert to gray scale
                
                %% combine D and Super res. for HSV
                hsvmap2(1:app.sz2map1,1:app.sz2map2,1)=app.normD2map2log; %set hue = color
                hsvmap2(1:app.sz2map1,1:app.sz2map2,2)=app.norm_ca_AC_deconv;
                hsvmap2(1:app.sz2map1,1:app.sz2map2,3)=ones(app.sz2map1,app.sz2map2); %set brightness
                app.hsv2rgbmap2=1-hsv2rgb(hsvmap2); %convert to rgb
            else
                app.hsv2rgbmap2 = zeros(size(app.hsv2rgbmap));
            end
            
            
            %% computation time of fcsSOFI combination
            Combine_after = clock;

            %Default figure(s) (add more axes?)
            cla(app.UIAxes,'reset');
            imagesc(app.UIAxes,app.hsv2rgbmap_deconv) % combined
            axis image
            title(app.UIAxes,'Combined fcsSOFI image')
            c = colorbar(app.UIAxes,'Ticks',[min(app.hsv2rgbmap(:)), max(app.hsv2rgbmap(:))],'TickLabels',{num2str(app.cmin), num2str(app.cmax)});
            title(c, 'log(D/nm^2s^-1)', 'FontSize', 10)  
            colormap(app.UIAxes,app.rgb_cmap_rs);
            close all

            close all

            if app.savethedata == 1 
                fit_curves = app.fitresult2;
                fit_parameters = double(app.converged_parameters);
                SOFI = app.srmap_hsv;
                D_map = app.Dmap;
                D_map_corrected = app.Dmap_corrected;
                Rsquare_map = app.R2map;
                fcsSOFI = app.hsv2rgbmap;
                fcsSOFI_cmap = app.rgb_cmap_rs;
                fcsSOFI_deconv = app.hsv2rgbmap_deconv;
                 
                % truncate '.mat' on filenames with ext included
                if strcmp(app.fname(end-3:end),'.mat') == 1
                    app.fname = app.fname(1:end-4);
                end
                
                if app.type == 1
                    save(strcat(app.fname,'_analyzed_brownian_',app.date),'fit_curves','fit_parameters','SOFI','D_map','D_map_corrected','Rsquare_map','fcsSOFI','fcsSOFI_cmap','fcsSOFI_deconv'); 
                elseif app.type == 2
                    D2_map = app.D2map;
                    D2_map_corrected = app.D2map_corrected;
                    save(strcat(app.fname,'_analyzed_2comp_brown_',app.date),'fit_curves','fit_parameters','SOFI','D_map','D_map_corrected','D2_map','D2_map_corrected','Rsquare_map','fcsSOFI','fcsSOFI_cmap','fcsSOFI_deconv');
                elseif app.type == 3
                    alpha_map = app.alphamap;
                    save(strcat(app.fname,'_analyzed_anomalous_',app.date),'fit_curves','fit_parameters','SOFI','D_map','D_map_corrected','alpha_map','Rsquare_map','fcsSOFI','fcsSOFI_cmap','fcsSOFI_deconv');
                end
            end      
            
             if app.savefigures == 1
    
                % alpha map if using anomalous model
                if app.type == 3
                    
                    sofiBinarized = imbinarize(app.srmap_hsv, 0.01);
                    %sofiBinarized = imbinarize(srmap_hsv, 'adaptive');
                    app.alphamap(sofiBinarized == 0) = 0;
                    
                    fa = figure;
                    imagesc(app.alphamap);
                    caxis([0 max(max((app.alphamap)))])
                    axis image
                    title('\alpha map')
                    c=colorbar;
                    c.Label.String = '\alpha';
                    c.Label.FontSize = 20;
                    set(gca,'xtick',[],'ytick',[])
                end
            
                % R-square Map
                f3 = figure;
                imagesc(app.R2map)
                caxis([0 1])
                axis image
                title('R^2 map')
                colorbar
            
                % SOFI super resolution image
                f5 = figure;
                imagesc(app.srmap_hsv);
                axis image
                title('SOFI super-resolution')
                set(gca,'xtick',[],'ytick',[])
                colormap(gray)
                
                % nondeconvolved SOFI
                f6 = figure;
                imagesc(app.im);
                axis image
                title('SOFI super-resolution')
                set(gca,'xtick',[],'ytick',[])
                colormap(gray)
            
                % Combined fcsSOFI image (nondeconvolved)
                f7 = figure;
                l=subplot(1,2,1); 
                k=subplot(1,2,1);
                imagesc(app.hsv2rgbmap) % combined
                sz=get(l,'position');
                axis image
                title('Non-deconvolved fcsSOFI image')
                set(gca,'xtick',[],'ytick',[])
                c=subplot(1,2,2); %colormap
                imagesc(rgb_cmap)
                sz2=get(l,'position');
                % set(c,'size',[sz(4)-sz(3)])
                set(gca,'ylim',[1 26],'ytick',[1 app.steps+1],'yticklabel',[num2str(app.cmin); num2str(app.cmax)],...
                    'xtick',[],'position',[0.77 0.413 0.02 0.22])
                ylabel('log(D/nm^2s^-1)')
                axis xy
                set(findall(gcf,'-property','FontSize'),'FontSize',14)
                
                % Combined fcsSOFI image (deconvolved)
                f9 = figure;
                l=subplot(1,2,1); 
                k=subplot(1,2,1);
                imagesc(app.hsv2rgbmap_deconv) % combined
                sz=get(l,'position');
                axis image
                title('Combined fcsSOFI image')
                set(gca,'xtick',[],'ytick',[])
                c=subplot(1,2,2); %colormap
                imagesc(rgb_cmap)
                sz2=get(l,'position');
                % set(c,'size',[sz(4)-sz(3)])
                set(gca,'ylim',[1 26],'ytick',[1 app.steps+1],'yticklabel',[num2str(app.cmin); num2str(app.cmax)],...
                    'xtick',[],'position',[0.77 0.413 0.02 0.22])
                ylabel('log(D/nm^2s^-1)')
                axis xy
                set(findall(gcf,'-property','FontSize'),'FontSize',14)
                
                % Second component fcsSOFI image
                if app.type == 2
                    f10 = figure;
                    l=subplot(1,2,1); 
                    k=subplot(1,2,1);
                    imagesc(app.hsv2rgbmap2) % combined
                    sz=get(l,'position');
                    axis image
                    title('Combined fcsSOFI image (2nd Component)')
                    set(gca,'xtick',[],'ytick',[])
                    c=subplot(1,2,2); %colormap
                    imagesc(rgb_cmap)
                    sz2=get(l,'position');
                    % set(c,'size',[sz(4)-sz(3)])
                    set(gca,'ylim',[1 26],'ytick',[1 app.steps+1],'yticklabel',[num2str(app.cmin); num2str(app.cmax)],...
                        'xtick',[],'position',[0.77 0.413 0.02 0.22])
                    ylabel('log(D/nm^2s^-1)')
                    axis xy
                    set(findall(gcf,'-property','FontSize'),'FontSize',14)
                end
                
                f8 = figure;
                plot(app.avgim(25,:)./max(app.avgim(25,:)),'-b')
                plot(app.im(25,:)./max(app.im(25,:)),'-r')
                plot(app.filtim(25,:)./max(app.filtim(25,:)),'-k')
                axis square
                title('Line sections')
                ylim([0 1])
                xlim([0 size(app.im(25,:),2)])
    
                  
                % Make figure directory
                switch app.type
                  case 1
                      app.figfolder = strcat(app.fname,'_figures_brownian_',app.date);
                      mkdir(app.figfolder)
                  case 2
                      app.figfolder = strcat(app.fname,'_figures_2comp_brown_',app.date);
                      mkdir(app.figfolder)
                      saveas(f10,[pwd '/', app.figfolder,'/fcsSOFI_image_2ndComp.fig']);
                  case 3
                      app.figfolder = strcat(app.fname,'_figures_anomalous_',app.date);
                      mkdir(app.figfolder)
                      saveas(fa,[pwd '/', app.figfolder,'/alpha_map.fig']);
                 end
                  %save figures 
                  saveas(f3,[pwd '/', app.figfolder, '/R2_map.fig']);
                  saveas(f5,[pwd '/', app.figfolder,'/SOFI_image.fig']);
%                   saveas(f6,[pwd '/', app.figfolder,'/nondeconvolved_image.fig']);
                  saveas(f7,[pwd '/', app.figfolder,'/nondeconv_fcsSOFI_image.fig']);
                  saveas(f8,[pwd '/', app.figfolder,'/line_sections.fig']);
                  saveas(f9,[pwd '/', app.figfolder,'/fcsSOFI.fig']);
                  
             close all; 
             
             end
             
             
            % average difusion coefficient
            avg_D = mean(app.Dmap_corrected(app.Dmap_corrected~=0));
            std_D = std(app.Dmap_corrected(app.Dmap_corrected~=0));
            
        
            app.totaltime = etime(clock,before);
            %% total computation time
            switch app.type
                case 1
                    app.ProgressTextArea.Value = {sprintf('fcsSOFI analysis complete (Brownian)\n'),...
                              sprintf('SOFI execution time: %6.2f seconds\n',etime(blink_after,blink_before)),...
                              sprintf('FCS execution time: %6.2f seconds',etime(Bin_after,Bin_before)),...
                              sprintf('Image fusion execution time: %6.2f seconds',etime(Combine_after,Combine_before)),...
                              sprintf('Total fcsSOFI execution time: %6.2f seconds\n',app.totaltime),...
                              sprintf('Average diffusion coefficient: %6.3e ± %6.3e nm^2 s^-1\n', avg_D, std_D)};
                                
                case 2
                    %average diffusion coefficient of second component
                    avg_D2 = mean(app.D2map_corrected(app.D2map_corrected~=0));
                    std_D2 = std(app.D2map_corrected(app.D2map_corrected~=0));
                    app.ProgressTextArea.Value = {sprintf('fcsSOFI analysis complete (2-Component Brownian)\n'),...
                              sprintf('SOFI execution time: %6.2f seconds\n',etime(blink_after,blink_before)),...
                              sprintf('FCS execution time: %6.2f seconds',etime(Bin_after,Bin_before)),...
                              sprintf('Image fusion execution time: %6.2f seconds',etime(Combine_after,Combine_before)),...
                              sprintf('Total fcsSOFI execution time: %6.2f seconds\n',app.totaltime),...
                              sprintf('Average diffusion coefficient (1st comp): %6.3e ± %6.3e nm^2 s^-1\n', avg_D, std_D),...
                              sprintf('Average diffusion coefficient (2nd comp): %6.3e ± %6.3e nm^2 s^-1\n', avg_D2, std_D2)};
                case 3
                    avg_alpha = mean(app.alphamap(app.alphamap~=0));
                    std_alpha = std(app.alphamap(app.alphamap~=0));
                    app.ProgressTextArea.Value = {sprintf('fcsSOFI analysis complete (Anomalous)\n'),...
                              sprintf('SOFI execution time: %6.2f seconds\n',etime(blink_after,blink_before)),...
                              sprintf('FCS execution time: %6.2f seconds',etime(Bin_after,Bin_before)),...
                              sprintf('Image fusion execution time: %6.2f seconds',etime(Combine_after,Combine_before)),...
                              sprintf('Total fcsSOFI execution time: %6.2f seconds\n',app.totaltime),...
                              sprintf('Average diffusion coefficient: %6.3e ± %6.3e nm^2 s^-1\n', avg_D, std_D),...
                              sprintf('Average anomalous power (alpha): %6.3f ± %6.3f\n', avg_alpha, std_alpha)};      
            end
            
            switch app.toggle_weights
                case 1
                    app.SinglePixelTextArea.Value = sprintf('Weights used for each pixel: w = G(tau, r) (FWHM^2 = 1/G(tau, r))');
                case 0
                    app.SinglePixelTextArea.Value = sprintf('Weighted fitting not used');
            end
            
            
            % write computation time to text file for remote access
            if app.store_execution_times == 1
                DateString = datestr(datetime);
                xdim = num2str((app.xmax-app.xmin+1)); ydim = num2str((app.ymax-app.ymin+1));
                fid = fopen('fcsSOFI_execution_times.txt','at');
                fprintf(fid, [DateString '\n']);
                fprintf(fid, ['Data File:' ' ' app.fname '.m' ' (' xdim 'x' ydim ' ' 'image)\n']); 
                fprintf(fid, ['Total execution time:' ' ' num2str(app.totaltime/60) ' ' 'minutes (' num2str(app.totaltime) ' ' 'seconds)\n']);
                if app.toggle_weights == 1
                    fprintf(fid,'Weighted fitting used (w = G(tau, r)\n');
                end
                fprintf(fid, ['Total GPU Only time:' ' ' num2str(fit_time) ' ' 'seconds\n\n']);
                fclose(fid);
            end            
            
            close all
            app.browse = 0;
        end

        % Callback function: CustomROICheckBox, D2stpEditField, 
        % DiffusionModelButtonGroup, DstpEditField, FWHMEditField, 
        % FilenameextEditField, PixelsizenmEditField, 
        % SavedataButtonGroup, SavefiguresButtonGroup, 
        % StoreExecTimesButtonGroup, TrialnumberEditField, 
        % WeightedfittingButtonGroup, alpha_minEditField, 
        % alpha_stpEditField, alpha_threshEditField, cmaxEditField, 
        % cminEditField, dTsEditField, normcoefEditField, 
        % satmaxEditField, satminEditField, scaleEditField, 
        % shiftEditField, stepsEditField, tmaxEditField, 
        % tminEditField, xmaxEditField, xminEditField, 
        % ymaxEditField, yminEditField
        function EditFieldValueChanged(app, event)
            %% Callback function for GUI components
            clc; close all; clear start_points;
            
            %File name ext.
            app.parameter = app.FilenameextEditField.Value;
            
            %Trial number(s)
            app.trialnos = app.TrialnumberEditField.Value;
            
            %Pixel size
            app.pixelsize = app.PixelsizenmEditField.Value;
            
            %dT
            app.dT = app.dTsEditField.Value;
            
            %FWHM (PSF)
            app.FWHM = app.FWHMEditField.Value;
            
            %Custom ROI checkbox
            app.custom_ROI = app.CustomROICheckBox.Value;
             
            %x min
            app.xmin = app.xminEditField.Value;
            
            %x max
            app.xmax = app.xmaxEditField.Value;
            
            %y min
            app.ymin = app.yminEditField.Value;
            
            %y max
            app.ymax =  app.ymaxEditField.Value;
            
            %t min
            app.tmin = app.tminEditField.Value;
            
            %t max
            app.tmax = app.tmaxEditField.Value;
            
            %D start point
            app.D_stp = app.DstpEditField.Value;
            
            %D2 start point
            app.D2_stp = app.D2stpEditField.Value;
            
            %Alpha start point
            app.alpha_stp = app.alpha_stpEditField.Value;
            
            %R2 Cutoff
            app.R2_cutoff = app.R2cutoffEditField.Value;
            
            %alpha threshold
            app.alpha_thresh = app.alpha_threshEditField.Value;
            
            %minimum alpha
            app.alpha_min = app.alpha_minEditField.Value;
            
            %SOFI saturation cutoff
            app.SOFI_sat_cutoff = app.SOFIsaturationcutoffEditField.Value;
             
            %Diffusion Type
            switch app.DiffusionModelButtonGroup.SelectedObject.Text
                case 'Brownian'
                    app.type = 1;  
                case '2-Comp Brownian'
                    app.type = 2;
                case 'Anomalous'
                    app.type = 3;  
            end
            
            % Weighted fitting? 
            switch app.WeightedfittingButtonGroup.SelectedObject.Text
                case 'Yes'
                    app.toggle_weights = 1;
                case 'No'
                    app.toggle_weights = 0;
            end
           
            %Save figures?
            switch app.SavefiguresButtonGroup.SelectedObject.Text
                case 'Yes'
                    app.savefigures = 1;  
                case 'No'
                    app.savefigures = 0;
            end
            
            %Store Execution times?
            switch app.StoreExecTimesButtonGroup.SelectedObject.Text
                case 'Yes'
                    app.store_execution_times = 1;  
                case 'No'
                    app.store_execution_times = 0;
            end
            
            %Save data?
            switch app.SavedataButtonGroup.SelectedObject.Text
                case 'Yes'
                    app.savethedata = 1;  
                case 'No'
                    app.savethedata = 0;
            end
            
            %caxis limits 
            app.cmin = app.cminEditField.Value;
            app.cmax = app.cmaxEditField.Value;
            
            % shift
            app.shift = app.shiftEditField.Value;
            
            % scale
            app.scale = app.scaleEditField.Value;
            
            %normcoef
            app.normcoef = app.normcoefEditField.Value;
            
            % steps
            app.steps = app.stepsEditField.Value;
            
            %saturation limits
            app.satmax = app.satmaxEditField.Value;
            app.satmin = app.satminEditField.Value;
             
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.fname = strcat(app.parameter,num2str(app.trialnos));
        
            % Load data
            load(app.fname); app.ProgressTextArea.Value = sprintf(strcat(app.fname,' loaded\n'));
            
            app.DataCombined=Data;
        
            % produce average image
            app.avgimage=sum(app.DataCombined(:,:,:),3)./size(app.DataCombined,3);
            
%             imagesc(app.UIAxes,app.avgimage(:,:,1))

            % Display first frame
            cla(app.UIAxes,'reset');
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            I = imagesc(app.UIAxes,app.avgimage(:,:,1),'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            axis image;
            title(app.UIAxes,'First Frame')
            set(gca,'xtick',[],'ytick',[])
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            close all;
            
        end

        % Button pushed function: DisplayROIButton
        function DisplayROIButtonPushed(app, event)
            cla(app.UIAxes,'reset');  
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            I = imagesc(app.UIAxes,app.avgimage(app.xmin:app.xmax,app.ymin:app.ymax,1),'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);  
            title(app.UIAxes,'First Frame at Selected ROI')
            set(gca,'xtick',[],'ytick',[])
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            close all
        end

        % Value changed function: DisplayFigureDropDown
        function DisplayFigureDropDownValueChanged(app, event)
            switch app.DisplayFigureDropDown.Value
                
                case 'Combined fcsSOFI image'
                    cla(app.UIAxes,'reset');
                    imagesc(app.UIAxes,app.hsv2rgbmap_deconv) % combined
                    axis image
                    title(app.UIAxes,'Combined fcsSOFI image')
                    c = colorbar(app.UIAxes,'Ticks',[min(app.hsv2rgbmap(:)), max(app.hsv2rgbmap(:))],'TickLabels',{num2str(app.cmin), num2str(app.cmax)});
                    title(c, 'log(D/nm^2s^-1)', 'FontSize', 10)  
                    colormap(app.UIAxes,app.rgb_cmap_rs);
                    close all
               
               
                case 'Non-deconvolved fcsSOFI image'
                    cla(app.UIAxes,'reset');
                    imagesc(app.UIAxes,app.hsv2rgbmap) % combined
                    axis image
                    title(app.UIAxes,'Non-deconvolved fcsSOFI image')
                    c = colorbar(app.UIAxes,'Ticks',[min(app.hsv2rgbmap(:)), max(app.hsv2rgbmap(:))],'TickLabels',{num2str(app.cmin), num2str(app.cmax)});
                    title(c, 'log(D/nm^2s^-1)', 'FontSize', 10)  
                    colormap(app.UIAxes,app.rgb_cmap_rs);
                    close all


%                 case 'Diffraction-limited image'
%                     cla(app.UIAxes,'reset');
%                     imagesc(app.UIAxes,app.avgimage(:,:,1))
%                     axis image
%                     title(app.UIAxes,'Average Diffraction-Limited Image')
%                     close all
%                  
%                 case 'SOFI image'
%                     cla(app.UIAxes,'reset');
%                     imagesc(app.UIAxes,app.srmap_hsv);
%                     axis image
%                     title(app.UIAxes,'SOFI super-resolution')
%                     colormap(app.UIAxes,gray)   
%                     close all
%                     
%                 case 'Non-deconvolved SOFI image'
%                     cla(app.UIAxes,'reset');
%                     imagesc(app.UIAxes,app.im);
%                     axis image
%                     title(app.UIAxes,'Non-deconvolved SOFI')
%                     colormap(app.UIAxes,gray)   
%                     close all
%                 
%                 case 'Line sections'
%                     cla(app.UIAxes,'reset');
%                     plot(app.UIAxes,app.avgim(25,:)./max(app.avgim(25,:)),'-b')
%                     hold(app.UIAxes,'on') 
%                     plot(app.UIAxes,app.im(25,:)./max(app.im(25,:)),'-r')
%                     plot(app.UIAxes,app.filtim(25,:)./max(app.filtim(25,:)),'-k')
%                     axis square
%                     title(app.UIAxes,'Line sections')
%                     ylim(app.UIAxes,[0 1])
%                     xlim(app.UIAxes,[0 size(app.im(25,:),2)])
%                     close all
                
                case 'R-square map'
                    cla(app.UIAxes,'reset');
                    imagesc(app.UIAxes,app.R2map)
                    caxis(app.UIAxes,[0 1]);
                    axis image
                    title(app.UIAxes,'R^2 map');
                    c = colorbar(app.UIAxes);
                    title(c, 'R-Square', 'FontSize', 10)
                    close all
                    
               case 'alpha map**'    
                    cla(app.UIAxes,'reset');
                    imagesc(app.UIAxes,app.alphamap);
                    caxis(app.UIAxes,[0 max(max((app.alphamap)))]);
                    axis image;
                    title('\alpha map');
                    c=colorbar(app.UIAxes);
                    title(c,'\alpha','FontSize',20);
                    set(gca,'xtick',[],'ytick',[]);
                    close all
                    
                case '2nd component fcsSOFI image*'
                    cla(app.UIAxes,'reset');
                    imagesc(app.UIAxes,app.hsv2rgbmap2) % combined
                    axis image
                    title(app.UIAxes,'Combined fcsSOFI image (2nd comp)')
                    c = colorbar(app.UIAxes,'Ticks',[min(app.hsv2rgbmap(:)), max(app.hsv2rgbmap(:))],'TickLabels',{num2str(app.cmin), num2str(app.cmax)});
                    title(c, 'log(D/nm^2s^-1)', 'FontSize', 10)  
                    colormap(app.UIAxes,app.rgb_cmap_rs);
                    close all
                   
                    
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.browse = 1;
            app.trialnos = 1;
            app.parameter = [];
            [app.browsed_file, ~] = uigetfile('*.mat');  %open a mat file
            % Load data
            
            load(app.browsed_file); app.ProgressTextArea.Value = sprintf(strcat(app.browsed_file,' loaded\n'));
            app.DataCombined=Data;
            
            % produce average image
            app.avgimage=sum(app.DataCombined(:,:,:),3)./size(app.DataCombined,3);
            
            cla(app.UIAxes,'reset');
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            I = imagesc(app.UIAxes,app.avgimage(:,:,1),'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            axis image;
            title(app.UIAxes,'First Frame')
            set(gca,'xtick',[],'ytick',[])
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            close all;
        end

        % Callback function: ColumnIndexEditField, 
        % DisplaySinglePixelCurveFitButton, RowIndexEditField
        function DisplayButtonPushed(app, event)
            app.row_index = app.RowIndexEditField.Value;
            app.column_index = app.ColumnIndexEditField.Value;
            
            x2=app.fitresult2(app.row_index,app.column_index).rawdata(:,1);
            y2=app.fitresult2(app.row_index,app.column_index).rawdata(:,2);
            N = max(y2);
            
            cla(app.UIAxes,'reset');
            ax = app.UIAxes;
            semilogx(app.UIAxes,x2,1/N.*y2,'or','LineWidth',2)
            hold(app.UIAxes,'on') 
            semilogx(app.UIAxes,x2',1/N.*app.fitresult2(app.row_index,app.column_index).model_fit,'--k','LineWidth',2)
            ax.YDir = 'normal'; ax.XMinorTick = 'on'; ax.YMinorTick = 'on';
            xlabel(app.UIAxes,'\tau')
            ylabel(app.UIAxes,'G(\tau)')
            legend(app.UIAxes,'Raw Data','Fit Result');
            title(app.UIAxes,['Curve Fit for Pixel ' '(' num2str(app.row_index) ',' num2str(app.column_index ) ')']); 
            hold(app.UIAxes,'off')
            close all
            
            fprintf('\nPixel (%i,%i):',app.row_index,app.column_index );
            
            if app.type == 2
            app.SinglePixelTextArea.Value = {sprintf('Pixel (%i,%i)\n\n',app.row_index,app.column_index ),...
                                  sprintf('D1:         %6.3e\n',app.Dmap_corrected(app.row_index,app.column_index )),...
                                  sprintf('D2:         %6.3e\n',app.D2map_corrected(app.row_index,app.column_index )),...
                                  sprintf('log10(D1):  %6.3f\n',app.Dmap2log(app.row_index,app.column_index )),...
                                  sprintf('log10(D2):  %6.3f\n\n',app.D2map2log(app.row_index,app.column_index )),...
                                  sprintf('R-square:  %6.4f',app.R2map(app.row_index,app.column_index ))};   
            
            elseif app.type == 3
            app.SinglePixelTextArea.Value = {sprintf('Pixel (%i,%i)\n\n',app.row_index,app.column_index ),...
                                  sprintf('D:         %6.3e\n',app.Dmap_corrected(app.row_index,app.column_index )),...
                                  sprintf('log10(D):  %6.3f\n\n',app.Dmap2log(app.row_index,app.column_index )),...
                                  sprintf('alpha:     %6.4f\n\n',app.alphamap(app.row_index,app.column_index )),...
                                  sprintf('R-square:  %6.4f',app.R2map(app.row_index,app.column_index ))};
                              
                    
            else
            app.SinglePixelTextArea.Value = {sprintf('Pixel (%i,%i)\n\n',app.row_index,app.column_index ),...
                                  sprintf('D:         %6.3e\n',app.Dmap_corrected(app.row_index,app.column_index )),...
                                  sprintf('log10(D):  %6.3f\n\n',app.Dmap2log(app.row_index,app.column_index )),...
                                  sprintf('R-square:  %6.4f',app.R2map(app.row_index,app.column_index ))};
            end
        end

        % Button pushed function: RedrawfcsSOFIButton
        function RedrawfcsSOFIButtonPushed(app, event)
            cla(app.UIAxes,'reset');
                        
            app.Dmap2log=log10(app.Dmap_corrected);
            app.normDmap2log=app.Dmap2log./app.normcoef;
           
                        
            maxvalue = app.cmax/app.normcoef;
            
            for i=1:size(app.normDmap2log,1)
                for j=1:size(app.normDmap2log,2)
                    if app.normDmap2log(i,j)==0
                        app.normDmap2log(i,j)=0;
                    else
                        app.normDmap2log(i,j)=(app.normDmap2log(i,j)-app.shift).*app.scale;
                    end
                end
            end
            
            
            ncmin=((app.cmin/app.normcoef)-app.shift).*app.scale;
            ncmax=((app.cmax/app.normcoef)-app.shift).*app.scale;
            
            cgrad=[ncmin:((ncmax-ncmin)/app.steps):ncmax]';%create gradient
            
            cmap(1:app.steps+1,1,1)=cgrad; %set hue = color
            cmap(1:app.steps+1,1,2)=ones(app.steps+1,1); %set saturation ("gray")
            cmap(1:app.steps+1,1,3)=ones(app.steps+1,1); %set brightness
            rgb_cmap=1-hsv2rgb(cmap); %convert to rgb  
            
            clear app.Dmap_hsv;
                  
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,1)=app.normDmap2log; %set hue = color
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,2)=ones(app.szmap1,app.szmap2); %set saturation ("gray")
            app.Dmap_hsv(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.Dmap_hsv=1-hsv2rgb(real(app.Dmap_hsv)); %convert to rgb
            
            
            sofi = app.srmap_hsv;
            for i=1:size(sofi,1)
                for j=1:size(sofi,2)
                    if sofi(i,j)<app.satmin
                        sofi(i,j)=app.satmin;
                    end
                    if sofi(i,j)>app.satmax
                        sofi(i,j)=app.satmax;
                    end
                end
            end
            
            sofi=sofi./max(max(sofi)); %renormalize so on scale 0-1
            
            sofi_deconv = app.srmap_hsv_deconv;
            for i=1:size(sofi_deconv,1)
                for j=1:size(sofi_deconv,2)
                    if sofi_deconv(i,j)<app.satmin
                        sofi_deconv(i,j)=app.satmin;
                    end
                    if sofi_deconv(i,j)>app.satmax
                        sofi_deconv(i,j)=app.satmax;
                    end
                end
            end
            
            sofi_deconv=sofi_deconv./max(max(sofi_deconv)); %renormalize so on scale 0-1
            
            hsvmap(1:app.szmap1,1:app.szmap2,1)=app.normDmap2log; %set hue = color
            hsvmap(1:app.szmap1,1:app.szmap2,2)=sofi; %set saturation
            hsvmap(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness 
            app.hsv2rgbmap=1-hsv2rgb(real(hsvmap)); %convert to rgb
            %deconvolved
            %combine D and Super res. for HSV
            hsvmap_deconv(1:app.szmap1,1:app.szmap2,1)=app.normDmap2log; %set hue = color
            hsvmap_deconv(1:app.szmap1,1:app.szmap2,2)= sofi_deconv;
            hsvmap_deconv(1:app.szmap1,1:app.szmap2,3)=ones(app.szmap1,app.szmap2); %set brightness
            app.hsv2rgbmap_deconv=1-hsv2rgb(hsvmap_deconv); %convert to rgb
            

            if app.type == 2
                app.D2map2log=log10(app.D2map_corrected);
                app.normD2map2log=app.D2map2log./app.normcoef;
               
                            
%                 maxvalue = app.cmax/app.normcoef;
                
                for i=1:size(app.normD2map2log,1)
                    for j=1:size(app.normD2map2log,2)
                        if app.normD2map2log(i,j)==0
                            app.normD2map2log(i,j)=0;
                        else
                            app.normD2map2log(i,j)=(app.normD2map2log(i,j)-app.shift).*app.scale;
                        end
                    end
                end
                
                
                ncmin=((app.cmin/app.normcoef)-app.shift).*app.scale;
                ncmax=((app.cmax/app.normcoef)-app.shift).*app.scale;
                
                cgrad=[ncmin:((ncmax-ncmin)/app.steps):ncmax]';%create gradient
                
                cmap(1:app.steps+1,1,1)=cgrad; %set hue = color
                cmap(1:app.steps+1,1,2)=ones(app.steps+1,1); %set saturation ("gray")
                cmap(1:app.steps+1,1,3)=ones(app.steps+1,1); %set brightness
                rgb_cmap=1-hsv2rgb(cmap); %convert to rgb  
                
                clear app.Dmap_hsv;
                      
                app.D2map_hsv(1:app.sz2map1,1:app.sz2map2,1)=app.normD2map2log; %set hue = color
                app.D2map_hsv(1:app.sz2map1,1:app.sz2map2,2)=ones(app.sz2map1,app.sz2map2); %set saturation ("gray")
                app.D2map_hsv(1:app.sz2map1,1:app.sz2map2,3)=ones(app.sz2map1,app.sz2map2); %set brightness
                app.D2map_hsv=1-hsv2rgb(real(app.D2map_hsv)); %convert to rgb
                
                
%                 sofi = app.srmap_hsv;
%                 for i=1:size(sofi,1)
%                     for j=1:size(sofi,2)
%                         if sofi(i,j)<app.satmin
%                             sofi(i,j)=app.satmin;
%                         end
%                         if sofi(i,j)>app.satmax
%                             sofi(i,j)=app.satmax;
%                         end
%                     end
%                 end
%                 
%                 sofi=sofi./max(max(sofi)); %renormalize so on scale 0-1
                
                hsvmap2(1:app.szmap1,1:app.szmap2,1)=app.normD2map2log; %set hue = color
                hsvmap2(1:app.szmap1,1:app.szmap2,2)=sofi; %set saturation
                hsvmap2(1:app.szmap1,1:app.szmap2,3)=ones(app.sz2map1,app.sz2map2); %set brightness
                
                clear app.hsv2rgbmap2
                
                app.hsv2rgbmap2=1-hsv2rgb(real(hsvmap2)); %convert to rgb
            end
            
            cla(app.UIAxes,'reset');
            imagesc(app.UIAxes,app.hsv2rgbmap_deconv) % combined
            axis image
            title(app.UIAxes,'Combined fcsSOFI Image (redrawn)')
            c = colorbar(app.UIAxes,'Ticks',[min(app.hsv2rgbmap(:)), max(app.hsv2rgbmap(:))],'TickLabels',{num2str(app.cmin), num2str(app.cmax)});
            title(c, 'log(D/nm^2s^-1)', 'FontSize', 10)  
            colormap(app.UIAxes,app.rgb_cmap_rs);
            close all;
            
            if app.savefigures == 1
                % SOFI super resolution image
                f5 = figure;
                imagesc(sofi);
                axis image
                title('SOFI super-resolution')
                set(gca,'xtick',[],'ytick',[])
                colormap(gray)
                
                % Combined fcsSOFI image (nondeconvolved)
                f7 = figure;
                l=subplot(1,2,1); 
                k=subplot(1,2,1);
                imagesc(app.hsv2rgbmap) % combined
                sz=get(l,'position');
                axis image
                title('Non-deconvolved fcsSOFI image')
                set(gca,'xtick',[],'ytick',[])
                c=subplot(1,2,2); %colormap
                imagesc(rgb_cmap)
                sz2=get(l,'position');
                % set(c,'size',[sz(4)-sz(3)])
                set(gca,'ylim',[1 26],'ytick',[1 app.steps+1],'yticklabel',[num2str(app.cmin); num2str(app.cmax)],...
                    'xtick',[],'position',[0.77 0.413 0.02 0.22])
                ylabel('log(D/nm^2s^-1)')
                axis xy
                set(findall(gcf,'-property','FontSize'),'FontSize',14)
                
                % Combined fcsSOFI image (deconvolved)
                f9 = figure;
                l=subplot(1,2,1); 
                k=subplot(1,2,1);
                imagesc(app.hsv2rgbmap_deconv) % combined
                sz=get(l,'position');
                axis image
                title('Combined fcsSOFI image')
                set(gca,'xtick',[],'ytick',[])
                c=subplot(1,2,2); %colormap
                imagesc(rgb_cmap)
                sz2=get(l,'position');
                % set(c,'size',[sz(4)-sz(3)])
                set(gca,'ylim',[1 26],'ytick',[1 app.steps+1],'yticklabel',[num2str(app.cmin); num2str(app.cmax)],...
                    'xtick',[],'position',[0.77 0.413 0.02 0.22])
                ylabel('log(D/nm^2s^-1)')
                axis xy
                set(findall(gcf,'-property','FontSize'),'FontSize',14)
                
                if app.type == 2
                    f10 = figure;
                    l=subplot(1,2,1); 
                    k=subplot(1,2,1);
                    imagesc(app.hsv2rgbmap2) % combined
                    sz=get(l,'position');
                    axis image
                    title('Combined fcsSOFI image (2nd Component)')
                    set(gca,'xtick',[],'ytick',[])
                    c=subplot(1,2,2); %colormap
                    imagesc(rgb_cmap)
                    sz2=get(l,'position');
                    % set(c,'size',[sz(4)-sz(3)])
                    set(gca,'ylim',[1 26],'ytick',[1 app.steps+1],'yticklabel',[num2str(app.cmin); num2str(app.cmax)],...
                        'xtick',[],'position',[0.77 0.413 0.02 0.22])
                    ylabel('log(D/nm^2s^-1)')
                    axis xy
                    set(findall(gcf,'-property','FontSize'),'FontSize',14)
                    
                    saveas(f9,[pwd '/', app.figfolder,'/fcsSOFI_image_2ndComp.fig']);
                end
              
                
                saveas(f5,[pwd '/', app.figfolder,'/SOFI_image.fig']);
                saveas(f7,[pwd '/', app.figfolder,'/nondeconv_fcsSOFI_image.fig']);
                saveas(f9,[pwd '/', app.figfolder,'/fcsSOFI.fig']);
                  
                
                close all;
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FilenameextEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameextEditFieldLabel.Text = 'File name ext.';
            app.TrialnumberEditFieldLabel = uilabel(app.UIFigure);
            app.TrialnumberEditFieldLabel.Text = 'Trial number';
            app.FilenameextEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameextEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.FilenameextEditField.Value = 'dataset';
            app.TrialnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialnumberEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.TrialnumberEditField.Value = 77;
            app.PixelsizenmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size (nm)';
            app.PixelsizenmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsizenmEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.PixelsizenmEditField.Value = 50;
            app.dTsEditFieldLabel = uilabel(app.UIFigure);
            app.dTsEditFieldLabel.Text = 'dT (s)';
            app.dTsEditField = uieditfield(app.UIFigure, 'numeric');
            app.dTsEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.dTsEditField.Value = 0.04;
            app.xminEditFieldLabel = uilabel(app.UIFigure);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.UIFigure, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.xminEditField.Value = 1;
            app.xmaxEditFieldLabel = uilabel(app.UIFigure);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.xmaxEditField.Value = 30;
            app.yminEditField_3Label = uilabel(app.UIFigure);
            app.yminEditField_3Label.Text = 'y min';
            app.yminEditField = uieditfield(app.UIFigure, 'numeric');
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.yminEditField.Value = 1;
            app.ymaxEditFieldLabel = uilabel(app.UIFigure);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ymaxEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ymaxEditField.Value = 30;
            app.tminEditFieldLabel = uilabel(app.UIFigure);
            app.tminEditFieldLabel.Text = 't min';
            app.tminEditField = uieditfield(app.UIFigure, 'numeric');
            app.tminEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.tminEditField.Value = 1;
            app.tmaxEditFieldLabel = uilabel(app.UIFigure);
            app.tmaxEditFieldLabel.Text = 't max';
            app.tmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.tmaxEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.tmaxEditField.Value = 5000;
            app.DiffusionModelButtonGroup = uibuttongroup(app.UIFigure);
            app.DiffusionModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DiffusionModelButtonGroup.Title = 'Diffusion Model';
            app.BrownianButton = uitogglebutton(app.DiffusionModelButtonGroup);
            app.BrownianButton.Text = 'Brownian';
            app.BrownianButton.Value = true;
            app.CompBrownianButton = uitogglebutton(app.DiffusionModelButtonGroup);
            app.CompBrownianButton.Text = '2-Comp Brownian';
            app.AnomalousButton = uitogglebutton(app.DiffusionModelButtonGroup);
            app.AnomalousButton.Text = 'Anomalous';
            app.SavefiguresButtonGroup = uibuttongroup(app.UIFigure);
            app.SavefiguresButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SavefiguresButtonGroup.Title = 'Save figures?';
            app.YesButton = uitogglebutton(app.SavefiguresButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uitogglebutton(app.SavefiguresButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.SavedataButtonGroup = uibuttongroup(app.UIFigure);
            app.SavedataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SavedataButtonGroup.Title = 'Save data?';
            app.YesButton_2 = uitogglebutton(app.SavedataButtonGroup);
            app.YesButton_2.Text = 'Yes';
            app.NoButton_2 = uitogglebutton(app.SavedataButtonGroup);
            app.NoButton_2.Text = 'No';
            app.NoButton_2.Value = true;
            app.MicroscopyParametersLabel = uilabel(app.UIFigure);
            app.MicroscopyParametersLabel.Text = 'Microscopy Parameters';
            app.RegionofInterestinPixelsLabel = uilabel(app.UIFigure);
            app.RegionofInterestinPixelsLabel.Text = 'Region of Interest in Pixels';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.fcsSOFILabel = uilabel(app.UIFigure);
            app.fcsSOFILabel.Text = 'fcsSOFI';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LoadDataLabel = uilabel(app.UIFigure);
            app.LoadDataLabel.Text = 'Load Data';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DisplayROIButton = uibutton(app.UIFigure, 'push');
            app.DisplayROIButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayROIButtonPushed, true);
            app.DisplayROIButton.Text = 'Display ROI';
            app.DisplayFigureDropDownLabel = uilabel(app.UIFigure);
            app.DisplayFigureDropDownLabel.Text = 'Display Figure';
            app.DisplayFigureDropDown = uidropdown(app.UIFigure);
            app.DisplayFigureDropDown.Items = {'Combined fcsSOFI image', 'Non-deconvolved fcsSOFI image', 'R² map', 'α map**', '2nd component fcsSOFI image*'};
            app.DisplayFigureDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayFigureDropDownValueChanged, true);
            app.DisplayFigureDropDown.Value = 'Combined fcsSOFI image';
            app.ProgressTextArea = uitextarea(app.UIFigure);
            app.ProgressTextArea.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.ProgressTextArea.Editable = 'off';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ManualLoadLabel = uilabel(app.UIFigure);
            app.ManualLoadLabel.Text = 'Manual Load';
            app.RowIndexEditFieldLabel = uilabel(app.UIFigure);
            app.RowIndexEditFieldLabel.Text = 'Row Index';
            app.RowIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowIndexEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.RowIndexEditField.Value = 15;
            app.ColumnIndexEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnIndexEditFieldLabel.Text = 'Column Index';
            app.ColumnIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnIndexEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.ColumnIndexEditField.Value = 15;
            app.DisplaySinglePixelCurveFitButton = uibutton(app.UIFigure, 'push');
            app.DisplaySinglePixelCurveFitButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplaySinglePixelCurveFitButton.Text = 'Display Single Pixel Curve Fit';
            app.SinglePixelFitResultsLabel = uilabel(app.UIFigure);
            app.SinglePixelFitResultsLabel.Text = 'Single-Pixel Fit Results';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SinglePixelTextArea = uitextarea(app.UIFigure);
            app.SinglePixelTextArea.Editable = 'off';
            app.August52020Label = uilabel(app.UIFigure);
            app.August52020Label.Text = 'August 5, 2020';
            app.DiffusionConstantRangeCaxisscaleLabel = uilabel(app.UIFigure);
            app.DiffusionConstantRangeCaxisscaleLabel.Text = 'Diffusion Constant Range (C-axis scale)';
            app.ModelParameterSettingsLabel = uilabel(app.UIFigure);
            app.ModelParameterSettingsLabel.Text = 'Model Parameter Settings';
            app.MapSettingsLabel = uilabel(app.UIFigure);
            app.MapSettingsLabel.Text = 'α Map Settings**';
            app.AnomalousmodelonlyLabel = uilabel(app.UIFigure);
            app.AnomalousmodelonlyLabel.Text = '**Anomalous model only';
            app.CompmodelonlyLabel = uilabel(app.UIFigure);
            app.CompmodelonlyLabel.Text = '*2-Comp model only';
            app.RedrawfcsSOFIButton = uibutton(app.UIFigure, 'push');
            app.RedrawfcsSOFIButton.ButtonPushedFcn = createCallbackFcn(app, @RedrawfcsSOFIButtonPushed, true);
            app.RedrawfcsSOFIButton.Text = 'Redraw fcsSOFI';
            app.ofPSFpixelsLabel = uilabel(app.UIFigure);
            app.ofPSFpixelsLabel.Text = 'σ of PSF (pixels)';
            app.FWHMEditField = uieditfield(app.UIFigure, 'numeric');
            app.FWHMEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.FWHMEditField.Value = 2.7;
            app.StoreExecTimesButtonGroup = uibuttongroup(app.UIFigure);
            app.StoreExecTimesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.StoreExecTimesButtonGroup.Title = 'Store exec. times?';
            app.YesButton_3 = uitogglebutton(app.StoreExecTimesButtonGroup);
            app.YesButton_3.Text = 'Yes';
            app.NoButton_3 = uitogglebutton(app.StoreExecTimesButtonGroup);
            app.NoButton_3.Text = 'No';
            app.NoButton_3.Value = true;
            app.DstartpointnmsLabel = uilabel(app.UIFigure);
            app.DstartpointnmsLabel.Text = 'D start point (nm² s⁻¹)';
            app.DstpEditField = uieditfield(app.UIFigure, 'numeric');
            app.DstpEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DstpEditField.Value = 10000;
            app.DstartpointnmsLabel_2 = uilabel(app.UIFigure);
            app.DstartpointnmsLabel_2.Text = 'D₂ start point (nm² s⁻¹)*';
            app.D2stpEditField = uieditfield(app.UIFigure, 'numeric');
            app.D2stpEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.D2stpEditField.Value = 1000000;
            app.alphastartpointEditFieldLabel = uilabel(app.UIFigure);
            app.alphastartpointEditFieldLabel.Text = 'α start point**';
            app.alpha_stpEditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha_stpEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.alpha_stpEditField.Value = 1;
            app.maximumalphaallowedEditFieldLabel = uilabel(app.UIFigure);
            app.maximumalphaallowedEditFieldLabel.Text = 'Maximum α threshold**';
            app.alpha_threshEditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha_threshEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.alpha_threshEditField.Value = 2;
            app.cminlogDnmsLabel = uilabel(app.UIFigure);
            app.cminlogDnmsLabel.Text = 'cmin (log(D/(nm² s⁻¹)))';
            app.cminEditField = uieditfield(app.UIFigure, 'numeric');
            app.cminEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.cminEditField.Value = 2;
            app.cmaxlogDnm2s1EditFieldLabel = uilabel(app.UIFigure);
            app.cmaxlogDnm2s1EditFieldLabel.Text = 'cmax (log(D/(nm² s⁻¹)))';
            app.cmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.cmaxEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.cmaxEditField.Value = 6;
            app.WeightedfittingButtonGroup = uibuttongroup(app.UIFigure);
            app.WeightedfittingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.WeightedfittingButtonGroup.Title = 'Weighted fitting?';
            app.YesButton_4 = uitogglebutton(app.WeightedfittingButtonGroup);
            app.YesButton_4.Text = 'Yes';
            app.NoButton_4 = uitogglebutton(app.WeightedfittingButtonGroup);
            app.NoButton_4.Text = 'No';
            app.NoButton_4.Value = true;
            app.SOFIsaturationcutoffLabel = uilabel(app.UIFigure);
            app.SOFIsaturationcutoffLabel.Text = 'SOFI saturation cutoff**';
            app.SOFIsaturationcutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.SOFIsaturationcutoffEditField.Value = 0.02;
            app.R2cutoffEditFieldLabel = uilabel(app.UIFigure);
            app.R2cutoffEditFieldLabel.Text = 'D map R² cutoff';
            app.R2cutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.shiftEditFieldLabel = uilabel(app.UIFigure);
            app.shiftEditFieldLabel.Text = 'Shift';
            app.shiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.shiftEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.shiftEditField.Value = 0.05;
            app.scaleEditFieldLabel = uilabel(app.UIFigure);
            app.scaleEditFieldLabel.Text = 'Scale';
            app.scaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.scaleEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.scaleEditField.Value = 0.7;
            app.NormcoefLabel = uilabel(app.UIFigure);
            app.NormcoefLabel.Text = 'Norm coef.';
            app.normcoefEditField = uieditfield(app.UIFigure, 'numeric');
            app.normcoefEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.normcoefEditField.Value = 10;
            app.stepsEditFieldLabel = uilabel(app.UIFigure);
            app.stepsEditFieldLabel.Text = 'Steps';
            app.stepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.stepsEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.stepsEditField.Value = 25;
            app.satmaxEditField_2Label = uilabel(app.UIFigure);
            app.satmaxEditField_2Label.Text = 'Maximum saturation';
            app.satmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.satmaxEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.satmaxEditField.Value = 0.5;
            app.satminEditFieldLabel = uilabel(app.UIFigure);
            app.satminEditFieldLabel.Text = 'Minimum saturation';
            app.satminEditField = uieditfield(app.UIFigure, 'numeric');
            app.satminEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.HueSettingsLabel = uilabel(app.UIFigure);
            app.HueSettingsLabel.Text = 'Hue Settings';
            app.SaturationSettingsLabel = uilabel(app.UIFigure);
            app.SaturationSettingsLabel.Text = 'Saturation Settings';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.MinimumthresholdLabel = uilabel(app.UIFigure);
            app.MinimumthresholdLabel.Text = 'Minimum α threshold**';
            app.alpha_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.alpha_minEditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.CustomROICheckBox = uicheckbox(app.UIFigure);
            app.CustomROICheckBox.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.CustomROICheckBox.Text = 'Custom ROI?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fcsSOFI_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end